classdef bh_6dof_demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure_6DOF_DEMO_SELECTOR  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab_DEMO                     matlab.ui.container.Tab
        LabelDiscreteKnob            matlab.ui.control.Label
        DiscreteKnob_DEMO_WHEEL_A    matlab.ui.control.DiscreteKnob
        Tab_TIDY                     matlab.ui.container.Tab
        Button_CLEAR_BASE_WKSPACE    matlab.ui.control.Button
        LabelListBox                 matlab.ui.control.Label
        ListBox_BASE_WKSPACE         matlab.ui.control.ListBox
        Button_CLOSE_EDITOR          matlab.ui.control.Button
        Button_CLOSE_MODELS          matlab.ui.control.Button
        Tab_PRES                     matlab.ui.container.Tab
        Button_OPEN_PRES             matlab.ui.control.Button
        Button_PC_WARMUP             matlab.ui.control.Button
        Button_MODELS_ON_SCREEN      matlab.ui.control.Button
        Tab                          matlab.ui.container.Tab
        ListBox_TEACH_RESOURCES      matlab.ui.control.ListBox
        Tab_Reports                  matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        ListBox_REPORTS              matlab.ui.control.ListBox
        Tab2                         matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        Tab4                         matlab.ui.container.Tab
        LabelLamp                    matlab.ui.control.Label
        Lamp_SLDO                    matlab.ui.control.Lamp
        Label                        matlab.ui.control.Label
        Lamp_SLCD                    matlab.ui.control.Lamp
        Label2                       matlab.ui.control.Label
        Lamp_CST                     matlab.ui.control.Lamp
        Label3                       matlab.ui.control.Label
        Lamp_OPT                     matlab.ui.control.Lamp
        Label4                       matlab.ui.control.Label
        Lamp_SYMT                    matlab.ui.control.Lamp
        Label5                       matlab.ui.control.Label
        Lamp_ML                      matlab.ui.control.Lamp
        Label6                       matlab.ui.control.Label
        Lamp_SL                      matlab.ui.control.Lamp
        Label7                       matlab.ui.control.Label
        Lamp_CFT                     matlab.ui.control.Lamp
        Label8                       matlab.ui.control.Label
        Lamp_EMC                     matlab.ui.control.Lamp
        Label9                       matlab.ui.control.Label
        Lamp_SLC                     matlab.ui.control.Lamp
        Label10                      matlab.ui.control.Label
        Lamp_MLC                     matlab.ui.control.Lamp
        Button_PROD_CHECK            matlab.ui.control.Button
        TextArea3                    matlab.ui.control.TextArea
        DoyouhavetherequirdeproductsLabel  matlab.ui.control.Label
        TextArea2                    matlab.ui.control.TextArea
    end


    properties (Access = public)
        OBJ_bh_6dof_DS % bh_6dof_demo_selector
    end

    methods (Access = public)
        
        function do_product_check(app)
            DEFAULT_COLOR = [255 191 0]/255; % orange
            app.Lamp_SLDO.Color = DEFAULT_COLOR;
            app.Lamp_SLCD.Color = DEFAULT_COLOR;
            app.Lamp_CST.Color  = DEFAULT_COLOR;
            app.Lamp_OPT.Color  = DEFAULT_COLOR;
            app.Lamp_SYMT.Color = DEFAULT_COLOR;
            app.Lamp_ML.Color   = DEFAULT_COLOR;
            app.Lamp_SL.Color   = DEFAULT_COLOR;
            app.Lamp_CFT.Color  = DEFAULT_COLOR;
            app.Lamp_EMC.Color  = DEFAULT_COLOR;
            app.Lamp_SLC.Color  = DEFAULT_COLOR;
            app.Lamp_MLC.Color  = DEFAULT_COLOR;
            
            % just pause for a few seconds
            pause(2);
            
            % what products do you have
            v_S          = ver;
            prod_list_CE = {v_S.Name}';
            
            % update the lamps
            my_database_CE = ...
                { 'Simulink Design Optimization', 'Lamp_SLDO';
                  'Simulink Control Design',      'Lamp_SLCD';
                  'Control System Toolbox',       'Lamp_CST';
                  'Optimization Toolbox',         'Lamp_OPT';
                  'Symbolic Math Toolbox',        'Lamp_SYMT';
                  'Curve Fitting Toolbox',        'Lamp_CFT';
                  'MATLAB',                       'Lamp_ML';
                  'Simulink',                     'Lamp_SL';
                  'Embedded Coder',               'Lamp_EMC';
                  'Simulink Coder',               'Lamp_SLC';
                  'MATLAB Coder',                 'Lamp_MLC';
                  };
              NUM_LAMPS = size(my_database_CE,1);
              for kk=1:NUM_LAMPS
                  the_prod_str  = my_database_CE{kk,1};
                  the_field_str = my_database_CE{kk,2};
                  tf_i_have_prod = strcmp(prod_list_CE, the_prod_str);
                  
                  if(1==nnz(tf_i_have_prod))
                      app.(the_field_str).Color = 'green';
                  else
                      app.(the_field_str).Color = 'red';
                  end
                  
              end % for
        end % function do_product_check(app)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_6dof_DS = bh_6dof_demo_selector();
            
            app.UIFigure_6DOF_DEMO_SELECTOR.Tag = 'UIFigure_6DOF_DEMO_SELECTOR';
        end

        % Button pushed function: Button_CLEAR_BASE_WKSPACE
        function Button_CLEAR_BASE_WKSPACEButtonPushed(app, event)
            app.OBJ_bh_6dof_DS.clear_base();
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;
        end

        % Button pushed function: Button_CLOSE_EDITOR
        function Button_CLOSE_EDITORButtonPushed(app, event)
             app.OBJ_bh_6dof_DS.close_editor();
        end

        % Button pushed function: Button_CLOSE_MODELS
        function Button_CLOSE_MODELSButtonPushed(app, event)
            bdclose('all');
            %             A = find_system('type','block_diagram');
            %             if(isempty(A) )
            %                 return;
            %             end
            %             tf_is_lib = bdIsLibrary(A);
            %             
            %             A_models = A(~tf_is_lib);
            %             if(isempty(A_models) )
            %                 return;
            %             end
            %             
            %             for kk=1:length(A_models)
            %                close_system(A_models{}, false) 
            %             end
        end

        % Button pushed function: Button_MODELS_ON_SCREEN
        function Button_MODELS_ON_SCREENButtonPushed(app, event)
             app.OBJ_bh_6dof_DS.position_all_MD_on_screen();
        end

        % Button pushed function: Button_OPEN_PRES
        function Button_OPEN_PRESButtonPushed(app, event)
            app.OBJ_bh_6dof_DS.open_pres();
        end

        % Button pushed function: Button_PC_WARMUP
        function Button_PC_WARMUPButtonPushed(app, event)
            app.OBJ_bh_6dof_DS.do_pc_warmup();
        end

        % Button pushed function: Button_PROD_CHECK
        function Button_PROD_CHECKButtonPushed(app, event)
            app.do_product_check();
        end

        % Value changed function: DiscreteKnob_DEMO_WHEEL_A
        function DiscreteKnob_DEMO_WHEEL_AValueChanged(app, event)
            value = app.DiscreteKnob_DEMO_WHEEL_A.Value;
            a=9;
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'INERTIA'}
                        app.OBJ_bh_6dof_DS.task_principal_inertias();
                case {'ROT_EULER'}
                        app.OBJ_bh_6dof_DS.task_euler_rates();
                case {'DCM_DERIVATIVE'}
                        app.OBJ_bh_6dof_DS.task_dcm_derivative();      
                case {'QUATERNION'}
                        app.OBJ_bh_6dof_DS.task_quaternion(); 
                case {'ODE'}
                        app.OBJ_bh_6dof_DS.task_integrate_ode
                case {'CAL_F_AND_TQ'}
                        app.OBJ_bh_6dof_DS.task_calibrate_F_and_TQ();
                case {'CAL_ELEC_MOT'}
                        app.OBJ_bh_6dof_DS.task_CALIBRATION_electric_motor();
                case {'LINEARIZE_AND_CONT'}
                        app.OBJ_bh_6dof_DS.task_linearize_and_control();
                case {'FULL_MODEL'}
                        app.OBJ_bh_6dof_DS.task_final_models();
                case {'DISC_AND_CODE_GEN'}                    
                        app.OBJ_bh_6dof_DS.task_code_generation();
                case {'BEGIN@END'}
                        app.OBJ_bh_6dof_DS.task_begin_at_end();
                otherwise                  
                    error('###_ERRRO: UNknown DEMO wheel choice !');
            end
            
            A = evalin('base', 'whos');
            names_CE = {A.name}';
            if(0==length(names_CE))
                names_CE = {''};
            end
            app.ListBox_BASE_WKSPACE.Items = names_CE;
        end

        % Value changed function: ListBox_REPORTS
        function ListBox_REPORTSValueChanged(app, event)
            value = app.ListBox_REPORTS.Value;
            value = upper(value);
            if(strcmp(value,'-----') )
                return
            end
            app.OBJ_bh_6dof_DS.open_report(value)
        end

        % Value changed function: ListBox_TEACH_RESOURCES
        function ListBox_TEACH_RESOURCESValueChanged(app, event)
            value = app.ListBox_TEACH_RESOURCES.Value;
            app.OBJ_bh_6dof_DS.open_resources(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure_6DOF_DEMO_SELECTOR = uifigure('Visible', 'off');
            app.UIFigure_6DOF_DEMO_SELECTOR.Name = 'Quadcopter DEMO';
            app.TabGroup = uitabgroup(app.UIFigure_6DOF_DEMO_SELECTOR);
            app.Tab_DEMO = uitab(app.TabGroup);
            app.Tab_DEMO.Title = 'DEMO';
            app.LabelDiscreteKnob = uilabel(app.Tab_DEMO);
            app.LabelDiscreteKnob.Text = 'DEMOS';
            app.DiscreteKnob_DEMO_WHEEL_A = uiknob(app.Tab_DEMO, 'discrete');
            app.DiscreteKnob_DEMO_WHEEL_A.Items = {'OFF', 'Inertia', 'ROT_Euler', 'DCM_derivative', 'Quaternion', 'ODE', 'CAL_F_and_TQ', 'CAL_Elec_mot', 'Linearize_and_CONT', 'FULL_MODEL', 'DISC_and_CODE_GEN', 'Begin@END'};
            app.DiscreteKnob_DEMO_WHEEL_A.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnob_DEMO_WHEEL_AValueChanged, true);
            app.DiscreteKnob_DEMO_WHEEL_A.Value = 'OFF';
            app.Tab_TIDY = uitab(app.TabGroup);
            app.Tab_TIDY.Title = 'TIDY';
            app.Button_CLEAR_BASE_WKSPACE = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLEAR_BASE_WKSPACE.ButtonPushedFcn = createCallbackFcn(app, @Button_CLEAR_BASE_WKSPACEButtonPushed, true);
            app.Button_CLEAR_BASE_WKSPACE.Text = 'Clear BASE workspace';
            app.LabelListBox = uilabel(app.Tab_TIDY);
            app.LabelListBox.Text = 'Workspace';
            app.ListBox_BASE_WKSPACE = uilistbox(app.Tab_TIDY);
            app.Button_CLOSE_EDITOR = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLOSE_EDITOR.ButtonPushedFcn = createCallbackFcn(app, @Button_CLOSE_EDITORButtonPushed, true);
            app.Button_CLOSE_EDITOR.Text = 'Close Editor';
            app.Button_CLOSE_MODELS = uibutton(app.Tab_TIDY, 'push');
            app.Button_CLOSE_MODELS.ButtonPushedFcn = createCallbackFcn(app, @Button_CLOSE_MODELSButtonPushed, true);
            app.Button_CLOSE_MODELS.Text = 'Close all Models ';
            app.Tab_PRES = uitab(app.TabGroup);
            app.Tab_PRES.Title = 'PRES';
            app.Button_OPEN_PRES = uibutton(app.Tab_PRES, 'push');
            app.Button_OPEN_PRES.ButtonPushedFcn = createCallbackFcn(app, @Button_OPEN_PRESButtonPushed, true);
            app.Button_OPEN_PRES.Text = 'Open Presentation';
            app.Button_PC_WARMUP = uibutton(app.Tab_PRES, 'push');
            app.Button_PC_WARMUP.ButtonPushedFcn = createCallbackFcn(app, @Button_PC_WARMUPButtonPushed, true);
            app.Button_PC_WARMUP.Text = 'PC Warmup';
            app.Button_MODELS_ON_SCREEN = uibutton(app.Tab_PRES, 'push');
            app.Button_MODELS_ON_SCREEN.ButtonPushedFcn = createCallbackFcn(app, @Button_MODELS_ON_SCREENButtonPushed, true);
            app.Button_MODELS_ON_SCREEN.Text = 'Models on Screen';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'TEACH_RES';
            app.ListBox_TEACH_RESOURCES = uilistbox(app.Tab);
            app.ListBox_TEACH_RESOURCES.Items = {'COURSEWARES_WEB', 'COURSEWARES_LOCAL', 'ONRAMP', 'HARDWARE_SUPPORT', 'HELLO'};
            app.ListBox_TEACH_RESOURCES.ValueChangedFcn = createCallbackFcn(app, @ListBox_TEACH_RESOURCESValueChanged, true);
            app.ListBox_TEACH_RESOURCES.Value = 'HELLO';
            app.Tab_Reports = uitab(app.TabGroup);
            app.Tab_Reports.Title = 'REPORTS';
            app.TextArea = uitextarea(app.Tab_Reports);
            app.TextArea.Value = {'Select the REPORT you want:'};
            app.ListBox_REPORTS = uilistbox(app.Tab_Reports);
            app.ListBox_REPORTS.Items = {'-----', 'PRINCIPAL_INERTIA', 'EULER_RATES', 'DCM_DERIVATIVE', 'CALIBRATE_TQ_F', 'CALIBRATE_MOTOR', 'SOLVING_ODES', 'CONTROL_LINEARIZE', 'CONTROL_DESIGN', 'PASSIVE_ROTATIONS', 'ACTIVE_ROTATIONS', 'PARALLEL_AXIS', '3_BLADED_PROPELLER'};
            app.ListBox_REPORTS.ValueChangedFcn = createCallbackFcn(app, @ListBox_REPORTSValueChanged, true);
            app.ListBox_REPORTS.Value = '-----';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'SYSTEM_CHECK';
            app.TabGroup2 = uitabgroup(app.Tab2);
            app.Tab4 = uitab(app.TabGroup2);
            app.Tab4.Title = 'PRODUCTS';
            app.LabelLamp = uilabel(app.Tab4);
            app.LabelLamp.Text = 'Simulink Design Optimization';
            app.Lamp_SLDO = uilamp(app.Tab4);
            app.Label = uilabel(app.Tab4);
            app.Label.Text = 'Simulink Control Design';
            app.Lamp_SLCD = uilamp(app.Tab4);
            app.Label2 = uilabel(app.Tab4);
            app.Label2.Text = 'Control System Toolbox';
            app.Lamp_CST = uilamp(app.Tab4);
            app.Label3 = uilabel(app.Tab4);
            app.Label3.Text = 'Optimization Toolbox';
            app.Lamp_OPT = uilamp(app.Tab4);
            app.Label4 = uilabel(app.Tab4);
            app.Label4.Text = 'Symbolic Math Toolbox';
            app.Lamp_SYMT = uilamp(app.Tab4);
            app.Label5 = uilabel(app.Tab4);
            app.Label5.Text = 'MATLAB';
            app.Lamp_ML = uilamp(app.Tab4);
            app.Label6 = uilabel(app.Tab4);
            app.Label6.Text = 'Simulink';
            app.Lamp_SL = uilamp(app.Tab4);
            app.Label7 = uilabel(app.Tab4);
            app.Label7.Text = 'Curve Fitting Toolbox';
            app.Lamp_CFT = uilamp(app.Tab4);
            app.Label8 = uilabel(app.Tab4);
            app.Label8.Text = 'Embedded Coder';
            app.Lamp_EMC = uilamp(app.Tab4);
            app.Label9 = uilabel(app.Tab4);
            app.Label9.Text = 'Simulink Coder';
            app.Lamp_SLC = uilamp(app.Tab4);
            app.Label10 = uilabel(app.Tab4);
            app.Label10.Text = 'MATLAB Coder';
            app.Lamp_MLC = uilamp(app.Tab4);
            app.Button_PROD_CHECK = uibutton(app.Tab4, 'push');
            app.Button_PROD_CHECK.ButtonPushedFcn = createCallbackFcn(app, @Button_PROD_CHECKButtonPushed, true);
            app.Button_PROD_CHECK.Text = 'CLICK ME to test your installation';
            app.TextArea3 = uitextarea(app.Tab4);
            app.TextArea3.Value = {'If you see any RED lamps, you may not be able to run SOME of the demos.'};
            app.DoyouhavetherequirdeproductsLabel = uilabel(app.Tab4);
            app.DoyouhavetherequirdeproductsLabel.Text = 'Do you have the requirde products ?';
            app.TextArea2 = uitextarea(app.UIFigure_6DOF_DEMO_SELECTOR);
            app.TextArea2.Value = {'Quadcopter DEMO selector'};
            % Show the figure after all components are created
            app.UIFigure_6DOF_DEMO_SELECTOR.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_6dof_demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure_6DOF_DEMO_SELECTOR)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure_6DOF_DEMO_SELECTOR)
        end
    end
end