classdef bh_quad_pendulum_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuadPendDEMOUIFigure      matlab.ui.Figure
        EditField                 matlab.ui.control.EditField
        TabGroup                  matlab.ui.container.TabGroup
        PENDULUMTab               matlab.ui.container.Tab
        Knob_PEND                 matlab.ui.control.DiscreteKnob
        TextArea                  matlab.ui.control.TextArea
        UIAxes_PEND               matlab.ui.control.UIAxes
        PlayamovieButton_PEND     matlab.ui.control.Button
        QUADCOPTERTab             matlab.ui.container.Tab
        Knob_QUAD                 matlab.ui.control.DiscreteKnob
        TextArea_2                matlab.ui.control.TextArea
        UIAxes_QUAD               matlab.ui.control.UIAxes
        Quad_with_pendulumTab     matlab.ui.container.Tab
        Knob_Q_and_P              matlab.ui.control.DiscreteKnob
        PlayamovieButton_Q_and_P  matlab.ui.control.Button
        UIAxes_Q_and_P            matlab.ui.control.UIAxes
        TextArea_3                matlab.ui.control.TextArea
        HELPTab                   matlab.ui.container.Tab
        TextArea_4                matlab.ui.control.TextArea
        HELP_guide_but            matlab.ui.control.Button
        HELP_seminar_pres         matlab.ui.control.Button
        DemoversionLabel          matlab.ui.control.Label
        June2019R2019aLabel       matlab.ui.control.Label
        UtilsTab                  matlab.ui.container.Tab
        Knob_Utils                matlab.ui.control.DiscreteKnob
        TextArea_5                matlab.ui.control.TextArea
    end

    properties (Access = public)
        OBJ_bh_DS % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.OBJ_bh_DS = bh_quad_pendulum_demo_selector_CLS();
            
            app.OBJ_bh_DS.plot_app_logo_pic(app.UIAxes_PEND, "PEND");
            app.OBJ_bh_DS.plot_app_logo_pic(app.UIAxes_QUAD, "QUAD");
            app.OBJ_bh_DS.plot_app_logo_pic(app.UIAxes_Q_and_P, "QUAD_AND_PEND");
        end

        % Button pushed function: HELP_guide_but
        function HELP_guide_butButtonPushed(app, event)
            app.OBJ_bh_DS.task_HELP_open_guide_pres(); 
        end

        % Value changed function: Knob_PEND
        function Knob_PENDValueChanged(app, event)
            value = app.Knob_PEND.Value;
           
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'EOM_DERIVE'}
                        app.OBJ_bh_DS.task_PEND_script_derive_eom();
                case {'MODEL_COMPONENT'}
                        app.OBJ_bh_DS.task_PEND_model_component();
                case {'DO_CONTROL_DESIGN'}
                        app.OBJ_bh_DS.task_PEND_script_do_control_design();                        
                case {'MODEL_FOR_LINEARISE'}
                        app.OBJ_bh_DS.task_PEND_model_for_linearise();                            
                case {'MODEL_FOR_SIMULATE'}
                        app.OBJ_bh_DS.task_PEND_model_for_simulation();   
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end            
            
            
        end

        % Value changed function: Knob_QUAD
        function Knob_QUADValueChanged(app, event)
            value = app.Knob_QUAD.Value;
           
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'EOM_DERIVE'}
                        app.OBJ_bh_DS.task_QUAD_script_derive_eom();
                case {'MODEL_LAG_UNIT'}
                        app.OBJ_bh_DS.task_QUAD_model_component();                        
                case {'MODEL_LAG_VS_NEWT'}
                        app.OBJ_bh_DS.task_QUAD_model_LAG_vs_NEWT();                            
                case {'DO_CONTROL_DESIGN'}
                        app.OBJ_bh_DS.task_QUAD_script_do_control_design();  
                case {'MODEL_LAG_WITH_LQI'}
                        app.OBJ_bh_DS.task_QUAD_model_LAG_with_LQI();                        
                case {'MODEL_SIMSCAPE'}
                        app.OBJ_bh_DS.task_QUAD_model_simscape();                            
                case {'MODEL_LAG_VS_SIMSCAPE'}
                        app.OBJ_bh_DS.task_QUAD_model_LAG_vs_simscape();                          
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                        
        end

        % Value changed function: Knob_Q_and_P
        function Knob_Q_and_PValueChanged(app, event)
            value = app.Knob_Q_and_P.Value;
            
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'EOM_DERIVE'}
                        app.OBJ_bh_DS.task_QP_script_derive_eom();
                case {'MODEL_LAG_UNIT'}
                        app.OBJ_bh_DS.task_QP_model_component();                                           
                case {'DO_CONTROL_DESIGN'}
                        app.OBJ_bh_DS.task_QP_script_do_control_design();  
                case {'DO_CONTROL_DESIGN_DISC'}
                        app.OBJ_bh_DS.task_QP_script_do_control_design_DISCRETE();                                               
                case {'MODEL_LAG_WITH_LQI'}
                        app.OBJ_bh_DS.task_QP_model_LAG_with_LQI();                        
                case {'MODEL_SIMSCAPE'}
                        app.OBJ_bh_DS.task_QP_model_simscape();   
                case {'MODEL_SIMSCAPE_DISC'}
                        app.OBJ_bh_DS.task_QP_model_simscape_DISCRETE();                                                 
                case {'MODEL_LAG_VS_SIMSCAPE'}
                        app.OBJ_bh_DS.task_QP_model_LAG_vs_simscape();                          
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                                    
            
        end

        % Button pushed function: PlayamovieButton_PEND
        function PlayamovieButton_PENDPushed(app, event)
            app.OBJ_bh_DS.task_button_play_movie("PEND"); 
        end

        % Button pushed function: PlayamovieButton_Q_and_P
        function PlayamovieButton_Q_and_PPushed(app, event)
            app.OBJ_bh_DS.task_button_play_movie("QUAD_AND_PEND"); 
        end

        % Callback function
        function PlayamovieButton_UNIPushed(app, event)
            
        end

        % Value changed function: Knob_Utils
        function Knob_UtilsValueChanged(app, event)
            value = app.Knob_Utils.Value;
            
            switch(upper(value))
                case {'OFF'}
                        % do NOTHING
                case {'SMD_EOM_DERIVE'}
                        app.OBJ_bh_DS.UTILS_open_smd_derivation();
                case {'SMD_MODEL'}
                        app.OBJ_bh_DS.UTILS_open_smd_model();                                           
                case {'SHOW_LAG_CLASS_FILES'}
                        app.OBJ_bh_DS.UTILS_show_classes();                        
                otherwise                  
                    error('###_ERROR: UNknown DEMO wheel choice !');
            end                                                
                        
        end

        % Button pushed function: HELP_seminar_pres
        function HELP_seminar_presButtonPushed(app, event)
            
            app.OBJ_bh_DS.task_HELP_open_seminar_pres();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuadPendDEMOUIFigure = uifigure('Visible', 'off');
            app.QuadPendDEMOUIFigure.Name = 'Quad Pend DEMO';
            app.EditField = uieditfield(app.QuadPendDEMOUIFigure, 'text');
            app.EditField.Value = 'Quadcopter balancing a pendulum DEMO Selector';
            app.TabGroup = uitabgroup(app.QuadPendDEMOUIFigure);
            app.PENDULUMTab = uitab(app.TabGroup);
            app.PENDULUMTab.Title = 'PENDULUM';
            app.Knob_PEND = uiknob(app.PENDULUMTab, 'discrete');
            app.Knob_PEND.Items = {'OFF', 'EOM_derive', 'model_component', 'do_CONTROL_design', 'model_for_linearise', 'model_for_simulate'};
            app.Knob_PEND.ValueChangedFcn = createCallbackFcn(app, @Knob_PENDValueChanged, true);
            app.Knob_PEND.Value = 'OFF';
            app.TextArea = uitextarea(app.PENDULUMTab);
            app.TextArea.Value = {'Explore a 3D pendulum'};
            app.UIAxes_PEND = uiaxes(app.PENDULUMTab);
            app.PlayamovieButton_PEND = uibutton(app.PENDULUMTab, 'push');
            app.PlayamovieButton_PEND.ButtonPushedFcn = createCallbackFcn(app, @PlayamovieButton_PENDPushed, true);
            app.PlayamovieButton_PEND.Text = 'Play a movie';
            app.QUADCOPTERTab = uitab(app.TabGroup);
            app.QUADCOPTERTab.Title = 'QUADCOPTER';
            app.Knob_QUAD = uiknob(app.QUADCOPTERTab, 'discrete');
            app.Knob_QUAD.Items = {'OFF', 'EOM_derive', 'model_LAG_unit', 'model_LAG_vs_NEWT', 'model_Simscape', 'model_LAG_vs_Simscape', 'do_CONTROL_design', 'model_LAG_with_LQI'};
            app.Knob_QUAD.ValueChangedFcn = createCallbackFcn(app, @Knob_QUADValueChanged, true);
            app.Knob_QUAD.Value = 'OFF';
            app.TextArea_2 = uitextarea(app.QUADCOPTERTab);
            app.TextArea_2.Value = {'Explore Lagrangian dynamics approach for deriving QUADCOPTER equations of motion'};
            app.UIAxes_QUAD = uiaxes(app.QUADCOPTERTab);
            app.Quad_with_pendulumTab = uitab(app.TabGroup);
            app.Quad_with_pendulumTab.Title = 'Quad_with_pendulum';
            app.Knob_Q_and_P = uiknob(app.Quad_with_pendulumTab, 'discrete');
            app.Knob_Q_and_P.Items = {'OFF', 'EOM_derive', 'model_LAG_unit', 'do_CONTROL_design', 'do_CONTROL_design_DISC', 'model_LAG_with_LQI', 'model_Simscape', 'model_LAG_vs_Simscape', 'model_Simscape_DISC'};
            app.Knob_Q_and_P.ValueChangedFcn = createCallbackFcn(app, @Knob_Q_and_PValueChanged, true);
            app.Knob_Q_and_P.Value = 'OFF';
            app.PlayamovieButton_Q_and_P = uibutton(app.Quad_with_pendulumTab, 'push');
            app.PlayamovieButton_Q_and_P.ButtonPushedFcn = createCallbackFcn(app, @PlayamovieButton_Q_and_PPushed, true);
            app.PlayamovieButton_Q_and_P.Text = 'Play a movie';
            app.UIAxes_Q_and_P = uiaxes(app.Quad_with_pendulumTab);
            app.TextArea_3 = uitextarea(app.Quad_with_pendulumTab);
            app.TextArea_3.Value = {'Explore a QUADCOPTER balancing a PENDULUM'};
            app.HELPTab = uitab(app.TabGroup);
            app.HELPTab.Title = 'HELP';
            app.TextArea_4 = uitextarea(app.HELPTab);
            app.TextArea_4.Value = {'Click the button below for a quick guide on how to use/interact with this demo !'};
            app.HELP_guide_but = uibutton(app.HELPTab, 'push');
            app.HELP_guide_but.ButtonPushedFcn = createCallbackFcn(app, @HELP_guide_butButtonPushed, true);
            app.HELP_guide_but.Text = 'Demo GUIDE and comments';
            app.HELP_seminar_pres = uibutton(app.HELPTab, 'push');
            app.HELP_seminar_pres.ButtonPushedFcn = createCallbackFcn(app, @HELP_seminar_presButtonPushed, true);
            app.HELP_seminar_pres.Text = 'Open Seminar presentation';
            app.DemoversionLabel = uilabel(app.HELPTab);
            app.DemoversionLabel.Text = 'Demo version:';
            app.June2019R2019aLabel = uilabel(app.HELPTab);
            app.June2019R2019aLabel.Text = {'June 2019'; 'R2019a'};
            app.UtilsTab = uitab(app.TabGroup);
            app.UtilsTab.Title = 'Utils';
            app.Knob_Utils = uiknob(app.UtilsTab, 'discrete');
            app.Knob_Utils.Items = {'OFF', 'SMD_EOM_derive', 'SMD_model', 'Show_Lag_Class_Files'};
            app.Knob_Utils.ValueChangedFcn = createCallbackFcn(app, @Knob_UtilsValueChanged, true);
            app.Knob_Utils.Value = 'OFF';
            app.TextArea_5 = uitextarea(app.UtilsTab);
            app.TextArea_5.Value = {'Introduce concept workflow and show Lagrangian implementation'};
            % Show the figure after all components are created
            app.QuadPendDEMOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_quad_pendulum_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuadPendDEMOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuadPendDEMOUIFigure)
        end
    end
end